classdef WebAppDemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Image               matlab.ui.control.Image
        VariableTuner       simulink.ui.control.VariableTuner
        TimeScope_3         matlab.ui.scope.TimeScope
        TimeScope_2         matlab.ui.scope.TimeScope
        TimeScope           matlab.ui.scope.TimeScope
        SimulationProgress  simulink.ui.control.SimulationProgress
        SimulationControls  simulink.ui.control.SimulationControls
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationControls = uisimcontrols(app.UIFigure);
            app.SimulationControls.Simulation = app.Simulation;
            app.SimulationProgress = uisimprogress(app.UIFigure);
            app.SimulationProgress.Simulation = app.Simulation;
            app.TimeScope = uitimescope(app.UIFigure);
            app.TimeScope.BufferLength = 500000;
            app.TimeScope.LegendNames = {'IPrimary'; 'IPrimary_Ref'};
            app.TimeScope.LegendVisible = 'on';
            app.TimeScope.Title = 'IPrimary, IPrimary_Ref';
            app.TimeScope.YLabel = 'Current[A]';
            app.TimeScope.XLimitsMethod = 'wrap';
            app.TimeScope.YLimitsMethod = 'manual';
            app.TimeScope.XTimeSpan = 3e-05;
            app.TimeScope.XTimeUnits = 'auto';
            app.TimeScope_2 = uitimescope(app.UIFigure);
            app.TimeScope_2.BufferLength = 500000;
            app.TimeScope_2.LegendNames = {'iL'};
            app.TimeScope_2.LegendVisible = 'on';
            app.TimeScope_2.Title = 'iL';
            app.TimeScope_2.YLabel = 'Current[A]';
            app.TimeScope_2.XLimitsMethod = 'wrap';
            app.TimeScope_2.XTimeSpan = 3e-05;
            app.TimeScope_2.XTimeUnits = 'auto';
            app.TimeScope_3 = uitimescope(app.UIFigure);
            app.TimeScope_3.BufferLength = 500000;
            app.TimeScope_3.LegendNames = {'VpInv,VsInv'};
            app.TimeScope_3.LegendVisible = 'on';
            app.TimeScope_3.Title = 'VpInv,VsInv';
            app.TimeScope_3.YLabel = 'Voltage[V]';
            app.TimeScope_3.XLimitsMethod = 'wrap';
            app.TimeScope_3.XTimeSpan = 3e-05;
            app.TimeScope_3.XTimeUnits = 'auto';
            app.VariableTuner = uisimvartuner(app.UIFigure);
            app.VariableTuner.VariablesFilter = {'Vin', 'Vout'};
            app.VariableTuner.EnableLiveTuning = 'off';
            app.VariableTuner.Simulation = app.Simulation;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'PlantFIgure.PNG');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % Bind initialization
    methods (Access = protected)

        function createBindings(app)

            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/ADC:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/From1:1'], app.TimeScope_3);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/From2:1'], app.TimeScope_3);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/From3:1'], app.TimeScope);
            bind(app.Simulation.LoggedSignals, [app.Simulation.ModelName '/From5:1'], app.TimeScope_2);
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WebAppDemo

            % Associate the Simulink Model
            app.Simulation = simulation('DualActiveBridgePlant_CurrentCnt');

            % Create UIFigure and components
            createComponents(app)

            % Create bindings
            createBindings(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end